classdef InterfazAudio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FrecuenciaHzEditField       matlab.ui.control.NumericEditField
        FrecuenciaHzEditFieldLabel  matlab.ui.control.Label
        AmplitudEditField           matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel      matlab.ui.control.Label
        TipodeOndaDropDown          matlab.ui.control.DropDown
        TipodeOndaDropDownLabel     matlab.ui.control.Label
        FrecuenciadeMuestreoFsEditField  matlab.ui.control.NumericEditField
        FrecuenciadeMuestreoFsEditFieldLabel  matlab.ui.control.Label
        DuracinsEditField           matlab.ui.control.NumericEditField
        DuracinsEditFieldLabel      matlab.ui.control.Label
        GrabarButton                matlab.ui.control.Button
        PausarButton                matlab.ui.control.Button
        ReproducirButton            matlab.ui.control.Button
        GuardarButton               matlab.ui.control.Button
        CargarButton                matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        AudioData % Vector con las muestras de sonido
        Fs % Frecuencia de muestreo (Hz)
        RecorderObject % Objeto audiorecorder para capturar
        PlayerObject   % Objeto audioplayer para reproduccion y pausa
    end
    
    methods (Access = private)
        
        function actualizarGraficas(app) 
            if isempty(app.AudioData), return; end 
        
            N = length(app.AudioData); 
            t = (0:N-1) / app.Fs; 
        
            % 1. Dominio del Tiempo
            plot(app.UIAxes, t, app.AudioData); 
            title(app.UIAxes, 'Señal en el Dominio del Tiempo'); 
            xlabel(app.UIAxes, 'Tiempo (s)'); 
            ylabel(app.UIAxes, 'Amplitud (V)'); 
            grid(app.UIAxes, 'on'); 
        
            % 2. FFT (Transformada en Frecuencia)
            N = length(app.AudioData);
            X = abs(fft(app.AudioData)); 
            f = (0:N-1)*(app.Fs/N); 
            
            % Dibujar solo la primera mitad del espectro (frecuencias positivas)
            plot(app.UIAxes2, f(1:floor(N/2)), X(1:floor(N/2))); 
            title(app.UIAxes2, 'Espectro de Frecuencia (FFT)'); 
            xlabel(app.UIAxes2, 'Frecuencia (Hz)'); 
            ylabel(app.UIAxes2, 'Amplitud'); 
            grid(app.UIAxes2, 'on');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            % Abre cuadro de diálogo para seleccionar archivo 
            [filename, pathname] = uigetfile({'\*.wav;\*.mp3;\*.au', 'Archivos de Audio (\*.wav, \*.mp3, \*.au)'}); 
            if isequal(filename, 0), return; end % El usuario canceló la selección 
            
            % Leer vector de muestras (app.AudioData) y frecuencia de muestreo (app.Fs) 
            [app.AudioData, app.Fs] = audioread(fullfile(pathname, filename)); 
            
            % Actualizar interfaz visual 
            app.actualizarGraficas();
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            if isempty(app.AudioData) 
                uialert(app.UIFigure, 'No hay datos de audio para guardar.', 'Atención'); 
                return; 
            end 
            
            % Cuadro de diálogo para elegir nombre y ubicación 
            [filename, pathname] = uiputfile('\*.wav', 'Guardar Audio Como'); 
            if isequal(filename, 0), return; end 
            
            % Guardar archivo en formato WAV 
            audiowrite(fullfile(pathname, filename), app.AudioData, app.Fs);
        end

        % Button pushed function: ReproducirButton
        function ReproducirButtonPushed(app, event)
            if isempty(app.AudioData) || isempty(app.Fs)
                uialert(app.UIFigure, 'Cargue, grabe o genere un audio primero.', 'Aviso');
                return;
            end

            % Si estaba pausado, reanuda la reproducción
            if ~isempty(app.PlayerObject) && isplaying(app.PlayerObject)
                return; % Ya se está reproduciendo
            elseif ~isempty(app.PlayerObject) && isdone(app.PlayerObject) == 0
                resume(app.PlayerObject);
            else
                % Crear e iniciar un nuevo objeto de reproducción de audio
                app.PlayerObject = audioplayer(app.AudioData, app.Fs);
                play(app.PlayerObject);
            end
        end

        % Button pushed function: PausarButton
        function PausarButtonPushed(app, event)
            if ~isempty(app.PlayerObject) && isplaying(app.PlayerObject)
                pause(app.PlayerObject); % Pausa la reproducción del sonido
            end
        end

        % Button pushed function: GrabarButton
        function GrabarButtonPushed(app, event)
            % Leer parámetros de la interfaz
            if isempty(app.FrecuenciadeMuestreoFsEditField.Value) || app.FrecuenciadeMuestreoFsEditField.Value <= 0
                app.Fs = 44100; % Frecuencia por defecto
            else
                app.Fs = app.FrecuenciadeMuestreoFsEditField.Value;
            end
            
            duracion = app.DuracinsEditField.Value; % Duración en segundos
            if isempty(duracion) || duracion <= 0
                duracion = 3; % Valor por defecto
            end
    
            % Crear objeto audiorecorder (16 bits, 1 canal / Mono)
            app.RecorderObject = audiorecorder(app.Fs, 16, 1);
            
            % Iniciar grabación síncrona
            recordblocking(app.RecorderObject, duracion);
            
            % Obtener el vector de datos grabado
            app.AudioData = getaudiodata(app.RecorderObject);
            
            % Actualizar ejes y gráficas
            app.actualizarGraficas();
        end

        % Value changed function: TipodeOndaDropDown
        function TipodeOndaDropDownValueChanged(app, event)
            % 1. Leer parámetros introducidos en los campos de texto
            f0 = app.FrecuenciaHzEditField.Value; % Frecuencia en Hz
            A = app.AmplitudEditField.Value;       % Amplitud
            
            % Validar que haya frecuencia y amplitud mayor que 0
            if f0 <= 0 || A <= 0
                return; % No hace nada si los valores son 0 o negativos
            end
            
            % Frecuencia de muestreo (por defecto 44100 Hz si está vacía o es 0)
            if isempty(app.FrecuenciadeMuestreoFsEditField.Value) || app.FrecuenciadeMuestreoFsEditField.Value <= 0
                app.Fs = 44100;
            else
                app.Fs = app.FrecuenciadeMuestreoFsEditField.Value;
            end

            % Duración (por defecto 2 segundos si está vacía o es 0)
            duracion = app.DuracinsEditField.Value;
            if isempty(duracion) || duracion <= 0
                duracion = 2;
            end

            % Vector de tiempo t
            t = 0:(1/app.Fs):(duracion - 1/app.Fs);

            % 2. Generar el vector según lo elegido en el menú desplegable
            switch app.TipodeOndaDropDown.Value
                case 'senoidal'
                    app.AudioData = A * sin(2*pi*f0*t)';
                case 'cuadrada'
                    app.AudioData = A * square(2*pi*f0*t)';
                case 'triangular'
                    app.AudioData = A * sawtooth(2*pi*f0*t, 0.5)';
                case 'diente de sierra'
                    app.AudioData = A * sawtooth(2*pi*f0*t)';
            end

            % 3. Dibujar la señal en los dos ejes
            app.actualizarGraficas();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señal')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'x(t)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'FFT')
            xlabel(app.UIAxes2, 'muestras')
            ylabel(app.UIAxes2, 'amplitud')
            zlabel(app.UIAxes2, 'Z')
            app.CargarButton = uibutton(app.UIFigure, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Text = 'Cargar';
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.ReproducirButton = uibutton(app.UIFigure, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.PausarButton = uibutton(app.UIFigure, 'push');
            app.PausarButton.ButtonPushedFcn = createCallbackFcn(app, @PausarButtonPushed, true);
            app.PausarButton.Text = 'Pausar';
            app.GrabarButton = uibutton(app.UIFigure, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Text = 'Grabar';
            app.DuracinsEditFieldLabel = uilabel(app.UIFigure);
            app.DuracinsEditFieldLabel.Text = 'Duración (s)';
            app.DuracinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrecuenciadeMuestreoFsEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciadeMuestreoFsEditFieldLabel.Text = 'Frecuencia de Muestreo (Fs)';
            app.FrecuenciadeMuestreoFsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TipodeOndaDropDownLabel = uilabel(app.UIFigure);
            app.TipodeOndaDropDownLabel.Text = 'Tipo de Onda';
            app.TipodeOndaDropDown = uidropdown(app.UIFigure);
            app.TipodeOndaDropDown.Items = {'senoidal', 'cuadrada', 'triangular', 'diente de sierra'};
            app.TipodeOndaDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodeOndaDropDownValueChanged, true);
            app.TipodeOndaDropDown.Value = 'senoidal';
            app.AmplitudEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrecuenciaHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciaHzEditFieldLabel.Text = 'Frecuencia (Hz)';
            app.FrecuenciaHzEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfazAudio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end